classdef drone_track_builder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CompetitionTrackBuilderUIFigure  matlab.ui.Figure
        HelpButton                      matlab.ui.control.Button
        SaveWaypointsButton             matlab.ui.control.Button
        LoadWaypointsButton             matlab.ui.control.Button
        UpdateVirtualWorldButton        matlab.ui.control.Button
        WaypointsPanel                  matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        ShowLandingMarkerButton         matlab.ui.control.StateButton
        HexcolorcodeoftrackRRGGBBPanel  matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        GenerateGroundPatternButton     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Ptiles % pattern tiles
        Wtiles % wapoint tiles
        wp % waipotins
        ph_wp % patch handlers for waypoints
        ph_ptrn; % patch handlers for paterns
        ph_ca % patch handler for clickable area
        land_coord % landing coordinates
        mh_land %landing handler
        path_color = [1 0 0]; % path color
        W=4;
        H=4;
        XYtable;

    end

    methods (Access = private)

        function drawPatch(app)
            w=app.W;
            h=app.H;
            if ~isempty(app.ph_ca)
                delete(app.ph_ca);
                app.ph_ca=[];
            end
            app.ph_ca = patch(app.UIAxes,[0 w w 0],[0 0 h h],[1 1 1],'EdgeColor','none');
            alpha(app.ph_ca,0);
            %app.ph_ca.ButtonDownFcn = @(src,event) app.axClicked(event);
            app.UIAxes.ButtonDownFcn = @(src,event) app.axClicked(event);
            hold(app.UIAxes,"on");
        end

        function axClicked(app,event)
            loc=event.IntersectionPoint;
            app.wp = [app.wp;loc(1:2)];
            app.UITable.Data = app.wp(:,1:2);
            drawWaypoints(app);
            drawPatch(app);
        end

        function drawWaypoints(app)
            if size(app.wp,1)>1
                delete(app.ph_wp)
                app.ph_wp=[];
                delete(app.mh_land);
                app.mh_land=[];
                [app.Wtiles,app.land_coord] = line_patches(app,app.wp,0.1);
                for ii=1:length(app.Wtiles)
                    app.ph_wp(ii) = patch(app.UIAxes,app.Wtiles(ii).x,app.Wtiles(ii).y,app.path_color,'EdgeColor','none');
                end
                val=app.ShowLandingMarkerButton.Value;
                if val == 0
                    app.mh_land=plot(app.UIAxes,app.land_coord(:,1),app.land_coord(:,2),'o','MarkerSize',18,'MarkerFaceColor',app.path_color,'MarkerEdgeColor','none');
                else
                    delete(app.mh_land);
                    app.mh_land=[];
                end
            end
        end

        function rgb = hex2rgb(app,hexString)
            if size(hexString,2)~=6
                uialert(app.CompetitionTrackBuilderUIFigure,'Valid hexadecimal color code must contain 6 characters','Error');
                rgb=app.path_color;
            else
                try
                    r = double(hex2dec(hexString(1:2)))/255;
                    g = double(hex2dec(hexString(3:4)))/255;
                    b = double(hex2dec(hexString(5:6)))/255;
                    rgb = [r, g, b];
                catch ME
                    uialert(app.CompetitionTrackBuilderUIFigure, ME.message ,'Error');
                    rgb = app.path_color;
                    app.EditField.Value = rgb2hex(app);
                end
                app.path_color = rgb;
            end
        end

        function hexVal = rgb2hex(app)
            rgb = app.path_color;
            rgb_norm = rgb*255;
            hexValString = dec2hex(rgb_norm);
            hexVal = reshape(hexValString', 1, 6);
        end

        function [tiles,land] = line_patches(~,points,w)
            % extend coordinates
            ext_points=[];
            for jj=1:size(points,1)-1
                pt1=points(jj,1:2);
                pt2=points(jj+1,1:2);
                ext1=pt2-pt1;
                ext2=pt1-pt2;
                [theta1,~] = cart2pol(ext1(1),ext1(2));
                [theta2,~] = cart2pol(ext2(1),ext2(2));
                [x1,y1]=pol2cart(theta1,-(w/2));
                [x2,y2]=pol2cart(theta2,+(w/2));
                ext_points=[ext_points; pt1+[x1 y1]; pt2-[x2 y2]];
            end
            % calculate patch coordinate
            idx=1;
            for ii=1:2:size(ext_points,1)-1
                sub=ext_points(ii:ii+1,1:2);
                data_tan=diff(sub);
                data_tan = data_tan./sqrt(sum(data_tan.^2,2));
                data_norm = data_tan*[0 -1;1 0];
                delta = w/2;
                data_offset = data_norm*delta;
                data_offset(end+1,:) = data_offset(end,:);
                data_new = sub + data_offset;
                data_new2 = sub - data_offset;
                tiles(idx).x= [data_new2(1,1) data_new2(2,1) data_new(2,1) data_new(1,1)];
                tiles(idx).y= [data_new2(1,2) data_new2(2,2) data_new(2,2) data_new(1,2)];
                idx=idx+1;
            end
            % calculate landing center
            pt1=ext_points(end-1,1:2);
            pt2=ext_points(end,1:2);
            ext2=pt1-pt2;
            [theta2,~] = cart2pol(ext2(1),ext2(2));
            land_dist = 0.25; % landing distance
            [x2,y2]=pol2cart(theta2,land_dist);
            land=pt2-[x2 y2];
        end

        function tiles = pattern_patches(~,w,h,tsize,th,tw,rnd_ok,rnd_per)
            count=1;
            per=(rnd_per/100);
            for ii = 1:1:ceil(w/tsize)
                for jj = 1:1:ceil(h/tsize)
                    if rnd_ok==1
                        th_new=tsize*(0.2+(per-0.2)*rand);
                        tw_new=tsize*(0.2+(per-0.2)*rand);
                    else
                        th_new = th;
                        tw_new = tw;
                    end
                    alpha = atan(tw_new/th_new);
                    angle = 2*pi*rand();
                    angle_vec = [angle-alpha angle+alpha angle-alpha+pi angle+alpha+pi];
                    tiles(count).x = ((ii-1)*tsize)+ tsize/2 + th_new/2.*cos(angle_vec);
                    tiles(count).y = ((jj-1)*tsize)+ tsize/2 + th_new/2.*sin(angle_vec);
                    count=count+1;
                end
            end
        end

        function lines2wrl(~,Wtiles,land_coord,start_coord,color,w)

            points = [];
            for ii=1:length(Wtiles)
                points=[points;Wtiles(ii).x' -0.02.*ones(4,1) -Wtiles(ii).y'];
            end
            idx=[];
            for jj=1:length(Wtiles)
                idx=[idx, (4*(jj-1):4*(jj-1)+3) -1];
            end

            delete(w.Lines.children);

            lines = vrnode(w,'Lines');
            setfield(lines,'rotation',[0 1 0 -1.5708]);
            LS = vrnode(lines,'children','Lines_Shape','Shape');

            LA = vrnode(LS,'appearance','','Appearance');
            LM = vrnode(LA,'material','','Material');
            setfield(LM,'diffuseColor',color);

            LG = vrnode(LS,'geometry','','IndexedFaceSet');
            setfield(LG,'convex','FALSE');
            setfield(LG,'solid','FALSE');
            setfield(LG,'coordIndex',idx');
            coordLG = vrnode(LG,'coord','','Coordinate');
            setfield(coordLG,'point',points);

            material = vrnode(w,'LS_Mat');
            setfield(material,'diffuseColor',color);

            if ~isempty(land_coord)
                w.LandingSpot.translation = [land_coord(2) -0.02 land_coord(1)];
                setfield(material,'transparency',0);
            else
                setfield(material,'transparency',1);
            end

            Quad = vrnode(w,'QuadBody');
            Quad.translation = [start_coord(2) 0.001765 start_coord(1)];

            iso = vrnode(w,'IsoNoRotation');
            iso.position = [start_coord(2) 0.001765 start_coord(1)] + [0.9009 0.6690 0.9900];

            onquad = vrnode(w,'OnQuadNoRotation');
            onquad.position = [start_coord(2) 0.001765 start_coord(1)] + [-3.0000 1.0000 0];

        end

        function markers2wrl(~,Ptiles,w)

            points = [];
            for ii=1:length(Ptiles)
                points=[points;Ptiles(ii).x' -0.021.*ones(4,1) -Ptiles(ii).y'];
            end
            idx=[];
            for jj=1:length(Ptiles)
                idx=[idx, (4*(jj-1):4*(jj-1)+3) -1];
            end

            delete(w.Markers.children);

            mark = vrnode(w,'Markers');
            setfield(mark,'rotation',[0 1 0 -1.5708]);
            MS = vrnode(mark,'children','Markers_Shape','Shape');

            MA = vrnode(MS,'appearance','','Appearance');
            MM = vrnode(MA,'material','','Material');
            setfield(MM,'diffuseColor',[0.8 0.8 0.8]);

            MG = vrnode(MS,'geometry','','IndexedFaceSet');
            setfield(MG,'convex','FALSE');
            setfield(MG,'solid','FALSE');
            setfield(MG,'coordIndex',idx');
            coordMG = vrnode(MG,'coord','','Coordinate');
            setfield(coordMG,'point',points);

        end


    end
    methods (Access = public)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.UIAxes)
            %app.W=app.Asize.Value;
            %app.H=app.Asize.Value;
            cla(app.UIAxes);
            app.UIAxes.XLim = [0 app.W];
            app.UIAxes.YLim = [0 app.H];
            %axis(app.UIAxes,'equal');
            drawPatch(app);
        end

        % Button pushed function: GenerateGroundPatternButton
        function GenerateGroundPatternButtonPushed(app, event)
            cla(app.UIAxes);
            app.ph_wp=[];
            app.ph_ptrn=[];
            w=app.W;
            h=app.H;
            app.UIAxes.XLim = [0 w];
            app.UIAxes.YLim = [0 h];
            app.Ptiles = pattern_patches(app,w,h,0.25,0.15,0.05,0,50);
            for ii=1:length(app.Ptiles)
                app.ph_ptrn(ii)=patch(app.UIAxes,app.Ptiles(ii).x,app.Ptiles(ii).y,[0.8 0.8 0.8]);
            end
            hold(app.UIAxes,'on')

            drawWaypoints(app);
            drawPatch(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.wp = [];
            delete(app.ph_wp(:));
            app.ph_wp=[];
            delete(app.ph_ptrn(:));
            app.ph_ptrn=[];
            delete(app.mh_land);
            app.mh_land=[];
            app.Wtiles = [];
            app.Ptiles = [];
            app.land_coord=[];
            app.UITable.Data = app.wp;
        end

        % Value changed function: ShowLandingMarkerButton
        function ShowLandingMarkerButtonValueChanged(app, event)
            value = app.ShowLandingMarkerButton.Value;
            if value==1
                app.ShowLandingMarkerButton.BackgroundColor='red';
            else
                app.ShowLandingMarkerButton.BackgroundColor='green';
            end
            drawWaypoints(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if ~isnan(newData)
                if ((newData < 0) || (newData > 4))
                    uialert(app.CompetitionTrackBuilderUIFigure,...
                        'Coordinates specified for the waypoint are outside boundary. Specify a value between 0 and 4.','Warning','Icon','warning');
                else
                    app.wp(indices(1),indices(2))=newData;
                end
            end
            app.UITable.Data = app.wp(:,1:2);
            drawWaypoints(app);
            figure(app.CompetitionTrackBuilderUIFigure);
        end

        % Callback function
        function Rnd_okValueChanged(app, event)
            value = app.Rnd_ok.Value;
            if value==1
                app.Rnd_perc.Enable = true;
                app.StripW.Enable = false;
                app.StripH.Enable = false;
            else
                app.Rnd_perc.Enable = false;
                app.StripW.Enable = true;
                app.StripH.Enable = true;
            end
            drawWaypoints(app);
        end

        % Button pushed function: UpdateVirtualWorldButton
        function UpdateVirtualWorldButtonPushed(app, event)
            f = waitbar(0,'Updating 3D graphics in the virtual world. This might take a few seconds.');
            w = vrworld('arena_tmpl.wrl');
            open(w);
            reload(w);
            waitbar(.33,f,'Updating 3D graphics in the virtual world. This might take a few seconds.');
            markers2wrl(app,app.Ptiles,w);
            waitbar(.67,f,'Updating 3D graphics in the virtual world. This might take a few seconds.');
            if size(app.wp,1)<2
                lines2wrl(app,app.Wtiles,[2 2],[2 2],app.path_color,w);
            else
                lines2wrl(app,app.Wtiles,app.land_coord,app.wp(1,1:2),app.path_color,w);
            end
            waitbar(.9,f, 'Updating 3D graphics in the virtual world. This might take a few seconds.');
            
            %Update init.posNED for simulation
            initNew = evalin('base','init');
            if size(app.wp,1)>=2
                initNew.posNED = [app.wp(1,2) app.wp(1,1) -0.0460];
            else
                initNew.posNED = [0.2 0.001765 2];
            end
            assignin('base', 'init', initNew);
            waitbar(1,f,'Finishing...');
            save(w,fullfile(fileparts(fileparts(mfilename('fullpath'))),'support','arena.wrl'));
            %vrdrawnow;
            close(w);
            close(f)
            % to refresh world in viewers
            w = vrworld(fullfile(fileparts(fileparts(mfilename('fullpath'))),'support','arena.wrl'));
            open(w);
            reload(w);
            vrdrawnow;
            close(w);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.path_color = hex2rgb(app,value);
            app.Label.BackgroundColor = app.path_color;
            drawWaypoints(app);
        end

        % Button pushed function: LoadWaypointsButton
        function LoadWaypointsButtonPushed(app, event)
            try
                [file,path] = uigetfile('*.csv');
                if file~=0
                    t=readmatrix([path file],'NumHeaderLines',0);
                    if isempty(find(ismissing(t))) && ~isempty(t)                        app.wp=[];
                        app.wp=t(:,1:2);
                        app.UITable.Data = app.wp;
                        drawWaypoints(app);
                    else
                        uialert(app.DroneTrackBuilderUIFigure,'Data in the file is corrupt or invalid.','Error');
                    end
                end
                figure(app.CompetitionTrackBuilderUIFigure);
            catch E
                uialert(app.CompetitionTrackBuilderUIFigure,'Unable to read data file.','Error');
            end
            figure(app.CompetitionTrackBuilderUIFigure);
        end

        % Button pushed function: SaveWaypointsButton
        function SaveWaypointsButtonPushed(app, event)
            if size(app.wp,1)>0
                [file,path] = uiputfile('waypoints.csv');
                if file~=0
                    t=array2table(app.wp,'VariableNames',{'East','North'});
                    writetable(t,[path file],'WriteVariableNames',false);
                end
            else
                uialert(app.CompetitionTrackBuilderUIFigure,'No waypoints are available to save. Add waypoints by drawing the track.','Waypoints Error!');
            end
            figure(app.CompetitionTrackBuilderUIFigure);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            codertarget.internal.helpView('parrot','parrottrackbuilder');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CompetitionTrackBuilderUIFigure = uifigure('Visible', 'off');
            app.CompetitionTrackBuilderUIFigure.Name = 'Competition Track Builder';
            app.UIAxes = uiaxes(app.CompetitionTrackBuilderUIFigure);
            xlabel(app.UIAxes, 'East [m]')
            ylabel(app.UIAxes, 'North [m]')
            app.UIAxes.XLim = [0 1];
            app.GenerateGroundPatternButton = uibutton(app.CompetitionTrackBuilderUIFigure, 'push');
            app.GenerateGroundPatternButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGroundPatternButtonPushed, true);
            app.GenerateGroundPatternButton.Text = {'Generate'; 'Ground Pattern'};
            app.ClearButton = uibutton(app.CompetitionTrackBuilderUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.HexcolorcodeoftrackRRGGBBPanel = uipanel(app.CompetitionTrackBuilderUIFigure);
            app.HexcolorcodeoftrackRRGGBBPanel.Title = 'Hex color code of track (#RRGGBB)';
            app.Label = uilabel(app.HexcolorcodeoftrackRRGGBBPanel);
            app.Label.Text = '';
            app.EditField = uieditfield(app.HexcolorcodeoftrackRRGGBBPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 'FF0000';
            app.Label_2 = uilabel(app.HexcolorcodeoftrackRRGGBBPanel);
            app.Label_2.Text = '#';
            app.ShowLandingMarkerButton = uibutton(app.CompetitionTrackBuilderUIFigure, 'state');
            app.ShowLandingMarkerButton.ValueChangedFcn = createCallbackFcn(app, @ShowLandingMarkerButtonValueChanged, true);
            app.ShowLandingMarkerButton.Text = {'Show'; 'Landing Marker'};
            app.WaypointsPanel = uipanel(app.CompetitionTrackBuilderUIFigure);
            app.WaypointsPanel.Title = 'Waypoints';
            app.UITable = uitable(app.WaypointsPanel);
            app.UITable.ColumnName = {'East'; 'North'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UpdateVirtualWorldButton = uibutton(app.CompetitionTrackBuilderUIFigure, 'push');
            app.UpdateVirtualWorldButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateVirtualWorldButtonPushed, true);
            app.UpdateVirtualWorldButton.Text = {'Update Virtual'; 'World'};
            app.LoadWaypointsButton = uibutton(app.CompetitionTrackBuilderUIFigure, 'push');
            app.LoadWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWaypointsButtonPushed, true);
            app.LoadWaypointsButton.Text = 'Load Waypoints';
            app.SaveWaypointsButton = uibutton(app.CompetitionTrackBuilderUIFigure, 'push');
            app.SaveWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWaypointsButtonPushed, true);
            app.SaveWaypointsButton.Text = 'Save Waypoints';
            app.HelpButton = uibutton(app.CompetitionTrackBuilderUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.CompetitionTrackBuilderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drone_track_builder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CompetitionTrackBuilderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CompetitionTrackBuilderUIFigure)
        end
    end
end